classdef GraphicalResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InductionMotorAnalyisisUIFigure  matlab.ui.Figure
        Menu           matlab.ui.container.Menu
        ExportMenu     matlab.ui.container.Menu
        TextFileMenu   matlab.ui.container.Menu
        ExcelFileMenu  matlab.ui.container.Menu
        Menu_2         matlab.ui.container.Menu
    end

    
    properties (Access = private)
        callingApp % object of the app that calls this activity to exchange info
        fromSimulation
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, simulated)
            movegui('center');
            
            app.callingApp = mainApp;
            app.fromSimulation = simulated;
            
            %Creates 3 Subdivisions
            tiledlayout(3,1)

            %First plot
            plot(nexttile,app.fromSimulation.i_dr_output.Time,app.fromSimulation.i_dr_output.Data,app.fromSimulation.i_qr_output.Time,app.fromSimulation.i_qr_output.Data);
            title('Stator Current')
            legend({'stator current/i-qs','stator current/i-ds'})
            xlabel('t(s)')
            ylabel('Amps')
            
            
            plot(nexttile, app.fromSimulation.Torque_output.Time,app.fromSimulation.Torque_output.Data);
            title('Electro-Magnetic Torque')
            xlabel('t(s)')
            ylabel('Nm')
            
            plot(nexttile,app.fromSimulation.i_ds_output.Time,app.fromSimulation.i_ds_output.Data,app.fromSimulation.i_qs_output.Time,app.fromSimulation.i_qs_output.Data);
            title('Rotor Current')
            legend({'rotor current/i-rs','rotor current/i-dr'})
            xlabel('t(s)')
            ylabel('Amps')

        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Callback function
        function ExitButtonPushed(app, event)
            close(app.InductionMotorAnalyisisUIFigure)
        end

        % Close request function: InductionMotorAnalyisisUIFigure
        function InductionMotorAnalyisisUIFigureCloseRequest(app, event)
            app.callingApp.GraphicalResultsButton.Enable = 'on';
            delete(app)
            
        end

        % Menu selected function: TextFileMenu
        function TextFileMenuSelected(app, event)
               
            %Here are created the arrays holding the information for the
            %table that will be exported 
            %Dr section
            time_dr =  app.fromSimulation.i_dr_output.Time;
            dr_amp =  app.fromSimulation.i_dr_output.Data;
            % Qr section
            time_qr =  app.fromSimulation.i_qr_output.Time;
            qr_amp =  app.fromSimulation.i_qr_output.Data;
                        
            %torque section for export
            time_torque =  app.fromSimulation.Torque_output.Time;
            torque_amp =  app.fromSimulation.Torque_output.Data;
                        
            %i_ds section for excel
            time_i_ds =  app.fromSimulation.i_ds_output.Time;
            i_ds_amp =  app.fromSimulation.i_ds_output.Data;
            % i_qs section
            time_i_qs =  app.fromSimulation.i_qs_output.Time;
            i_qs_amp =  app.fromSimulation.i_qs_output.Data;   
            
            Table1 = table(time_dr,dr_amp,time_qr, qr_amp,time_torque, torque_amp,time_i_ds,i_ds_amp,time_i_qs,i_qs_amp)
            writetable(Table1,'Graphical_Result.txt','WriteRowNames',true)  
        end

        % Menu selected function: ExcelFileMenu
        function ExcelFileMenuSelected(app, event)
                   
            %Here are created the arrays holding the information for the
            %table that will be exported 
            %Dr section
            time_dr =  app.fromSimulation.i_dr_output.Time;
            dr_amp =  app.fromSimulation.i_dr_output.Data;
            % Qr section
            time_qr =  app.fromSimulation.i_qr_output.Time;
            qr_amp =  app.fromSimulation.i_qr_output.Data;
                        
            %torque section for export
            time_torque =  app.fromSimulation.Torque_output.Time;
            torque_amp =  app.fromSimulation.Torque_output.Data;
                        
            %i_ds section for excel
            time_i_ds =  app.fromSimulation.i_ds_output.Time;
            i_ds_amp =  app.fromSimulation.i_ds_output.Data;
            % i_qs section
            time_i_qs =  app.fromSimulation.i_qs_output.Time;
            i_qs_amp =  app.fromSimulation.i_qs_output.Data;          
            Table1 = table(time_dr,dr_amp,time_qr, qr_amp,time_torque, torque_amp,time_i_ds,i_ds_amp,time_i_qs,i_qs_amp)
            writetable(Table1,'Graphical_Result.xlsx','WriteRowNames',true)  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InductionMotorAnalyisisUIFigure = uifigure('Visible', 'off');
            app.InductionMotorAnalyisisUIFigure.Name = 'Induction Motor Analyisis';
            app.InductionMotorAnalyisisUIFigure.CloseRequestFcn = createCallbackFcn(app, @InductionMotorAnalyisisUIFigureCloseRequest, true);
            app.Menu = uimenu(app.InductionMotorAnalyisisUIFigure);
            app.Menu.Text = 'Menu';
            app.ExportMenu = uimenu(app.Menu);
            app.ExportMenu.Text = 'Export';
            app.TextFileMenu = uimenu(app.ExportMenu);
            app.TextFileMenu.MenuSelectedFcn = createCallbackFcn(app, @TextFileMenuSelected, true);
            app.TextFileMenu.Text = 'Text File';
            app.ExcelFileMenu = uimenu(app.ExportMenu);
            app.ExcelFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcelFileMenuSelected, true);
            app.ExcelFileMenu.Text = 'Excel File';
            app.Menu_2 = uimenu(app.InductionMotorAnalyisisUIFigure);
            % Show the figure after all components are created
            app.InductionMotorAnalyisisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphicalResults(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InductionMotorAnalyisisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InductionMotorAnalyisisUIFigure)
        end
    end
end