classdef GridLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GridLaboratoryUIFigure       matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MotorParametersTab           matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        Panel_2                      matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        PolesSpinnerLabel            matlab.ui.control.Label
        PolesSpinner                 matlab.ui.control.Spinner
        FrequencySliderLabel         matlab.ui.control.Label
        FrequencySlider              matlab.ui.control.Slider
        FrequencyEditField           matlab.ui.control.NumericEditField
        SpeedEditFieldLabel          matlab.ui.control.Label
        SpeedEditField               matlab.ui.control.NumericEditField
        Image                        matlab.ui.control.Image
        PHASEINDUCTIONMOTORLabel     matlab.ui.control.Label
        GridVoltage                  matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        VrmsGridVKnob                matlab.ui.control.Knob
        VbLamp_11                    matlab.ui.control.Lamp
        VaLabel                      matlab.ui.control.Label
        VbLamp_12                    matlab.ui.control.Lamp
        VbLamp_13                    matlab.ui.control.Lamp
        VbLabel                      matlab.ui.control.Label
        VcLabel                      matlab.ui.control.Label
        VrmsEditFieldLabel           matlab.ui.control.Label
        VrmsEditField                matlab.ui.control.NumericEditField
        ParametersPanel              matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        RsEditFieldLabel             matlab.ui.control.Label
        RsEditField                  matlab.ui.control.NumericEditField
        XsEditFieldLabel             matlab.ui.control.Label
        XsEditField                  matlab.ui.control.NumericEditField
        XmEditFieldLabel             matlab.ui.control.Label
        XmEditField                  matlab.ui.control.NumericEditField
        XrEditFieldLabel             matlab.ui.control.Label
        XrEditField                  matlab.ui.control.NumericEditField
        RrEditFieldLabel             matlab.ui.control.Label
        RrEditField                  matlab.ui.control.NumericEditField
        StartButton                  matlab.ui.control.Button
        SetParametersButton          matlab.ui.control.Button
        MotorLoadPanel               matlab.ui.container.Panel
        GridLayout16                 matlab.ui.container.GridLayout
        LoadKnobLabel                matlab.ui.control.Label
        LoadTorqueKnob               matlab.ui.control.Knob
        LoadValueLabel               matlab.ui.control.Label
        LoadTorqueEditField          matlab.ui.control.NumericEditField
        DefaultParametersButton      matlab.ui.control.Button
        HelpButton                   matlab.ui.control.Button
        EraseAllButton               matlab.ui.control.Button
        SetParametersMessageBox      matlab.ui.control.EditField
        Panel_3                      matlab.ui.container.Panel
        GridLayout26                 matlab.ui.container.GridLayout
        FuseLampLabel                matlab.ui.control.Label
        FuseLamp                     matlab.ui.control.Lamp
        GraphicalResultsTab          matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        ExportDropDownLabel          matlab.ui.control.Label
        ExportDropDown               matlab.ui.control.DropDown
        TabGroup2                    matlab.ui.container.TabGroup
        GeneralResultsTab            matlab.ui.container.Tab
        GridLayout23                 matlab.ui.container.GridLayout
        GraphGeneralVoltage          matlab.ui.control.UIAxes
        GraphGeneralCurrent          matlab.ui.control.UIAxes
        GraphGeneralSpeed            matlab.ui.control.UIAxes
        GraphGeneralTorque           matlab.ui.control.UIAxes
        ElectromagneticTorqueTab     matlab.ui.container.Tab
        GridLayout20                 matlab.ui.container.GridLayout
        GraphTabTorque               matlab.ui.control.UIAxes
        SpeedTab                     matlab.ui.container.Tab
        GridLayout25                 matlab.ui.container.GridLayout
        GraphTabSpeed                matlab.ui.control.UIAxes
        PhaseCurrentsStatorTab       matlab.ui.container.Tab
        GridLayout22                 matlab.ui.container.GridLayout
        GraphTabCurrent              matlab.ui.control.UIAxes
        VoltageTab                   matlab.ui.container.Tab
        GridLayout24                 matlab.ui.container.GridLayout
        GraphTabVoltage              matlab.ui.control.UIAxes
        ReadMeTab                    matlab.ui.container.Tab
        GridLayout21                 matlab.ui.container.GridLayout
        ModeTextAreaLabel            matlab.ui.control.Label
        ModeTextArea                 matlab.ui.control.TextArea
        MotorTextAreaLabel           matlab.ui.control.Label
        MotorTextArea                matlab.ui.control.TextArea
        MotorTextArea_2Label         matlab.ui.control.Label
        MotorTextArea_2              matlab.ui.control.TextArea
        RelevantInformationTextAreaLabel  matlab.ui.control.Label
        RelevantInformationTextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        callingApp % Description
        simulated % Description
    end
    
    methods (Access = private)
        
        function plotGraphs(app)
            
            %----General Tab------- RTInductionMotorFeb24/Motor Model/Rotor speed
            %REFERENCE
            %legend(app.UIAxes_Stator,({'stator current/i-qs','stator current/i-ds'}));
            plot(app.GraphGeneralVoltage,app.simulated.VoltageInput.Time,app.simulated.VoltageInput.Data)
            legend(app.GraphGeneralVoltage,({'Va','Vb','Vc'}));
            plot(app.GraphGeneralCurrent,app.simulated.CurrentInput.Time,app.simulated.CurrentInput.Data)
            legend(app.GraphGeneralCurrent,({'Ia','Ib','Ic'}));
            plot(app.GraphGeneralSpeed,app.simulated.SpeedOutput.Time,app.simulated.SpeedOutput.Data)
            
            plot(app.GraphGeneralTorque,app.simulated.TorqueOutput.Time,app.simulated.TorqueOutput.Data)
           
            %---- Each Specific Tab----
            
            plot(app.GraphTabTorque,app.simulated.TorqueOutput.Time,app.simulated.TorqueOutput.Data)
            
            plot(app.GraphTabSpeed,app.simulated.SpeedOutput.Time,app.simulated.SpeedOutput.Data)
            
            plot(app.GraphTabVoltage,app.simulated.VoltageInput.Time,app.simulated.VoltageInput.Data)
            legend(app.GraphTabVoltage,({'Va','Vb','Vc'}));
            plot(app.GraphTabCurrent,app.simulated.CurrentInput.Time,app.simulated.CurrentInput.Data)
            legend(app.GraphTabCurrent,({'Ia','Ib','Ic'}));
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
           
            app.callingApp = mainApp;
            movegui(app.GridLaboratoryUIFigure,'center');
            load_system('simulation\RTInductionMotorFeb24.slx');   %loads the system into temp memory
            % so that the command 'bdroot' returns the last Simulink model loaded
            set_param([bdroot,'/SourceSelect'],'Value',num2str(0));
            
            app.FrequencySlider.Value=0;
        end

        % Value changed function: PolesSpinner
        function PolesSpinnerValueChanging(app, event)
            changingValue = event.Value; % reads the value changed in the event happening
            
            polesSpinner = num2str(changingValue); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
            
            set_param([bdroot,'/Motor Model/Poles'],'Value', polesSpinner);
        end

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)
            changingValue = event.Value; % reads the value changed in the event happening
             
            freqSlider = num2str(round(changingValue)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
            % round() avoids gettings values like 45.4168751
           
            set_param([bdroot,'/Grid Source/Frequency'],'Value', freqSlider);
            
            app.FrequencyEditField.Value = str2double(freqSlider);
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            valueEdit = app.FrequencyEditField.Value;
           
            freq = num2str(valueEdit);
            
            set_param([bdroot,'/Grid Source/Frequency'],'Value', freq);
            
            app.FrequencySlider.Value = str2double(freq);
            
        end

        % Value changed function: SpeedEditField
        function SpeedEditFieldValueChanged(app, event)
            value = app.SpeedEditField.Value;
            
            mspeed = num2str(value);
            set_param([bdroot,'/Vbyf with PWM/motor speed'],'Value', mspeed);
            
            
        end

        % Value changed function: VrmsGridVKnob
        function VrmsGridValueChanging(app, event)
            changingValue = event.Value;
            
            voltKnob = num2str(round(changingValue,1)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/GridVoltage'],'Value', voltKnob);

            app.VrmsEditField.Value =  str2double(voltKnob);
        end

        % Value changed function: VrmsEditField
        function VrmsEditFieldValueChanged(app, event)
            valueEdit = app.VrmsEditField.Value;
            
            Vrms = num2str(valueEdit);
           
            set_param([bdroot,'/GridVoltage'],'Value', Vrms);
            
            app.VrmsGridVKnob.Value= str2double(Vrms);
        end

        % Value changed function: LoadTorqueKnob
        function LoadTorqueKnobValueChanging(app, event)
            changingValue = event.Value;
            
            loadKnob = num2str(round(changingValue)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/Motor Model/Input Torque'],'Value', loadKnob);
           
            app.LoadTorqueEditField.Value =  str2double(loadKnob);
        end

        % Value changed function: LoadTorqueEditField
        function LoadTorqueEditFieldValueChanged(app, event)
            valueEdit = app.LoadTorqueEditField.Value;
            
            loadTorque = num2str(round(valueEdit));
           
            set_param([bdroot,'/Motor Model/Input Torque'],'Value', loadTorque);
            
            app.LoadTorqueKnob.Value = str2double(loadTorque);
        end

        % Button pushed function: DefaultParametersButton
        function DefaultParametersButtonPushed(app, event)
            app.RsEditField.Value=1;
            app.XsEditField.Value=0.003883;
            app.XmEditField.Value=0.1485;
            app.XrEditField.Value=0.009061;
            app.RrEditField.Value=1.06;
            app.FrequencySlider.Value=60;
            app.FrequencyEditField.Value = app.FrequencySlider.Value;
            app.PolesSpinner.Value=4;
            app.SpeedEditField.Value=1500;
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            %app.SetParametersButton.Enable = 'off';        
           
            % Setting the Resistances and Inductances 
                       
            Rs = num2str(app.RsEditField.Value);
            Ls = num2str(app.XsEditField.Value);
            Rr = num2str(app.RrEditField.Value);           
            Lr = num2str(app.XrEditField.Value);
            Lm = num2str(app.XmEditField.Value);
            
            
            set_param([bdroot,'/Motor Model/Rs (ohms)'],'Value',Rs);
            set_param([bdroot,'/Motor Model/Ls (H)'],'Value',Ls); 
            set_param([bdroot,'/Motor Model/Rr (ohms)'],'Value',Rr);
            set_param([bdroot,'/Motor Model/Lr (H)'],'Value',Lr);
            set_param([bdroot,'/Motor Model/Lm (H)'],'Value',Lm);
            
            
            % Setting the Poles
            
            polesSpinner = num2str(app.PolesSpinner.Value);
            
            set_param([bdroot,'/Motor Model/Poles'],'Value', polesSpinner);
            
            
            % Setting the Frequency
            
            freq = num2str(app.FrequencyEditField.Value);
            
            set_param([bdroot,'/Grid Source/Frequency'],'Value', freq);
           
            
            % Setting the Speed
           
            rpm= num2str(app.SpeedEditField.Value);
            set_param([bdroot,'/Vbyf with PWM/motor speed'],'Value',rpm);
            
            
            % Setting the Voltage

            Vrms = num2str(round(app.VrmsEditField.Value));
           
            set_param([bdroot,'/GridVoltage'],'Value', Vrms);
                        
            
            % Setting the Torque

            loadTorque = num2str(round(app.LoadTorqueEditField.Value));
            
            set_param([bdroot,'/Motor Model/Input Torque'],'Value', loadTorque);


            % Saving the current status of Simulink
            
            save_system('simulation\RTInductionMotorFeb24.slx');
            
            % Displaying Message after Setting is succesful
            
            app.SetParametersMessageBox.Visible = true;
            
            app.SetParametersMessageBox.Value='Parameters sent to model';
       
        end

        % Button pushed function: EraseAllButton
        function EraseAllButtonPushed(app, event)
            app.RsEditField.Value=0;
            app.XsEditField.Value=0;
            app.XmEditField.Value=0;
            app.XrEditField.Value=0;
            app.RrEditField.Value=0;
            app.SpeedEditField.Value=0;
            app.PolesSpinner.Value=0;
            app.FrequencySlider.Value=0;
            app.FrequencyEditField.Value = app.FrequencySlider.Value;
            
            app.VrmsGridVKnob.Value=0;
            app.VrmsEditField.Value= 0;
            
            app.LoadTorqueKnob.Value=0;
            app.LoadTorqueEditField.Value = 0;
            
            app.SetParametersMessageBox.Visible=false;
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            Help();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable ='off';
            app.FuseLamp.Color='red';
            %save_system('simulation/RTInductionMotorFeb24.slx');
            save_system('simulation\RTInductionMotorFeb24.slx');
            app.simulated = sim("simulation\RTInductionMotorFeb24.slx");
            
            %flag = app.simulated.Fuse_Result.Data;
            % Gab: I commented this because it was bugging and needed to
            % run the sim anyways


            plotGraphs(app);
            
            if (get_param(bdroot,'SimulationStatus') == 'stopped')
%                 if(flag==1)
%                 app.FuseLamp.Color='yellow';%blown fuse
%                 end
                app.StartButton.Enable ='on';
                 app.FuseLamp.Color='white';
            end
           app.SetParametersMessageBox.Visible='off';
    
        end

        % Close request function: GridLaboratoryUIFigure
        function GridLaboratoryUIFigureCloseRequest(app, event)
            app.callingApp.Experiment1Button.Enable = 'on';
            delete(app)
            
        end

        % Value changed function: ExportDropDown
        function ExportDropDownValueChanged(app, event)
            value = app.ExportDropDown.Value;
            
           switch value
               
               case 'Data (Excel)'
                   
               %torque
               time_torque = app.simulated.TorqueOutput.Time;
               torque=app.simulated.TorqueOutput.Data;
               %speed
               time_speed=app.simulated.SpeedOutput.Time;
               rpm=app.simulated.SpeedOutput.Data;
               %voltage
               time_voltage=app.simulated.VoltageInput.Time;
               voltage=app.simulated.VoltageInput.Data;
               %current
               time_current=app.simulated.CurrentInput.Time;
               current=app.simulated.CurrentInput.Data;
               %date
               t=now;
               d = datetime(floor(t),'ConvertFrom','datenum')%converts it to date and time. floor only gets the date only
               %file creation
               name=strcat('Grid-Lab-Data-',datestr(d),'.xlsx')
               Table_excel = table(torque,time_torque,time_speed,rpm,time_voltage,voltage,time_current,current)
               writetable(Table_excel,name,'WriteRowNames',true)       
               
               case 'Data (txt)'
                                      
               %torque
               time_torque = app.simulated.TorqueOutput.Time;
               torque=app.simulated.TorqueOutput.Data;
               %speed
               time_speed=app.simulated.SpeedOutput.Time;
               rpm=app.simulated.SpeedOutput.Data;
               %voltage
               time_voltage=app.simulated.VoltageInput.Time;
               voltage=app.simulated.VoltageInput.Data;
               %current
               time_current=app.simulated.CurrentInput.Time;
               current=app.simulated.CurrentInput.Data;
               %date
               t=now;
               d = datetime(floor(t),'ConvertFrom','datenum');%converts it to date and time. floor only gets the date only
               %file creation
               name=strcat('Grid-Lab-Data-',datestr(d),'.txt');
               Table_txt = table(torque,time_torque,time_speed,rpm,time_voltage,voltage,time_current,current);
               writetable(Table_txt,name,'WriteRowNames',true);   
                   
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GridLaboratoryUIFigure = uifigure('Visible', 'off');
            app.GridLaboratoryUIFigure.Name = 'Grid Laboratory';
            app.GridLaboratoryUIFigure.CloseRequestFcn = createCallbackFcn(app, @GridLaboratoryUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.GridLaboratoryUIFigure);
            app.MotorParametersTab = uitab(app.TabGroup);
            app.MotorParametersTab.Title = 'Motor Parameters';
            app.GridLayout5 = uigridlayout(app.MotorParametersTab);
            app.Panel_2 = uipanel(app.GridLayout5);
            app.GridLayout7 = uigridlayout(app.Panel_2);
            app.PolesSpinnerLabel = uilabel(app.GridLayout7);
            app.PolesSpinnerLabel.Text = 'Poles';
            app.PolesSpinner = uispinner(app.GridLayout7);
            app.PolesSpinner.Limits = [0 Inf];
            app.PolesSpinner.ValueChangedFcn = createCallbackFcn(app, @PolesSpinnerValueChanging, true);
            app.FrequencySliderLabel = uilabel(app.GridLayout7);
            app.FrequencySliderLabel.Text = 'Frequency ';
            app.FrequencySlider = uislider(app.GridLayout7);
            app.FrequencySlider.Limits = [0 60];
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.FrequencyEditField = uieditfield(app.GridLayout7, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.SpeedEditFieldLabel = uilabel(app.GridLayout7);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.GridLayout7, 'numeric');
            app.SpeedEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedEditFieldValueChanged, true);
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = 'EquivalentCircuitDiagram.png';
            app.PHASEINDUCTIONMOTORLabel = uilabel(app.GridLayout5);
            app.PHASEINDUCTIONMOTORLabel.Text = '3-PHASE INDUCTION MOTOR';
            app.GridVoltage = uipanel(app.GridLayout5);
            app.GridVoltage.Title = 'Grid 3-Phase Voltage';
            app.GridLayout10 = uigridlayout(app.GridVoltage);
            app.VrmsGridVKnob = uiknob(app.GridLayout10, 'continuous');
            app.VrmsGridVKnob.Limits = [0 240];
            app.VrmsGridVKnob.ValueChangedFcn = createCallbackFcn(app, @VrmsGridValueChanging, true);
            app.VbLamp_11 = uilamp(app.GridLayout10);
            app.VaLabel = uilabel(app.GridLayout10);
            app.VaLabel.Text = 'Va';
            app.VbLamp_12 = uilamp(app.GridLayout10);
            app.VbLamp_13 = uilamp(app.GridLayout10);
            app.VbLabel = uilabel(app.GridLayout10);
            app.VbLabel.Text = 'Vb';
            app.VcLabel = uilabel(app.GridLayout10);
            app.VcLabel.Text = 'Vc';
            app.VrmsEditFieldLabel = uilabel(app.GridLayout10);
            app.VrmsEditFieldLabel.Text = 'Vrms';
            app.VrmsEditField = uieditfield(app.GridLayout10, 'numeric');
            app.VrmsEditField.ValueChangedFcn = createCallbackFcn(app, @VrmsEditFieldValueChanged, true);
            app.ParametersPanel = uipanel(app.GridLayout5);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout6 = uigridlayout(app.ParametersPanel);
            app.RsEditFieldLabel = uilabel(app.GridLayout6);
            app.RsEditFieldLabel.Text = 'Rs';
            app.RsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XsEditFieldLabel = uilabel(app.GridLayout6);
            app.XsEditFieldLabel.Text = 'Xs';
            app.XsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XmEditFieldLabel = uilabel(app.GridLayout6);
            app.XmEditFieldLabel.Text = 'Xm';
            app.XmEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XrEditFieldLabel = uilabel(app.GridLayout6);
            app.XrEditFieldLabel.Text = 'Xr';
            app.XrEditField = uieditfield(app.GridLayout6, 'numeric');
            app.RrEditFieldLabel = uilabel(app.GridLayout6);
            app.RrEditFieldLabel.Text = 'Rr''';
            app.RrEditField = uieditfield(app.GridLayout6, 'numeric');
            app.StartButton = uibutton(app.GridLayout5, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SetParametersButton = uibutton(app.GridLayout5, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.MotorLoadPanel = uipanel(app.GridLayout5);
            app.MotorLoadPanel.Title = 'Motor Load';
            app.GridLayout16 = uigridlayout(app.MotorLoadPanel);
            app.LoadKnobLabel = uilabel(app.GridLayout16);
            app.LoadKnobLabel.Text = 'Load Knob';
            app.LoadTorqueKnob = uiknob(app.GridLayout16, 'continuous');
            app.LoadTorqueKnob.ValueChangedFcn = createCallbackFcn(app, @LoadTorqueKnobValueChanging, true);
            app.LoadValueLabel = uilabel(app.GridLayout16);
            app.LoadValueLabel.Text = 'Load Value ';
            app.LoadTorqueEditField = uieditfield(app.GridLayout16, 'numeric');
            app.LoadTorqueEditField.ValueChangedFcn = createCallbackFcn(app, @LoadTorqueEditFieldValueChanged, true);
            app.DefaultParametersButton = uibutton(app.GridLayout5, 'push');
            app.DefaultParametersButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultParametersButtonPushed, true);
            app.DefaultParametersButton.Text = 'Default Parameters';
            app.HelpButton = uibutton(app.GridLayout5, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.EraseAllButton = uibutton(app.GridLayout5, 'push');
            app.EraseAllButton.ButtonPushedFcn = createCallbackFcn(app, @EraseAllButtonPushed, true);
            app.EraseAllButton.Text = 'Erase All';
            app.SetParametersMessageBox = uieditfield(app.GridLayout5, 'text');
            app.SetParametersMessageBox.Editable = 'off';
            app.SetParametersMessageBox.Visible = 'off';
            app.Panel_3 = uipanel(app.GridLayout5);
            app.GridLayout26 = uigridlayout(app.Panel_3);
            app.FuseLampLabel = uilabel(app.GridLayout26);
            app.FuseLampLabel.Text = 'Fuse';
            app.FuseLamp = uilamp(app.GridLayout26);
            app.GraphicalResultsTab = uitab(app.TabGroup);
            app.GraphicalResultsTab.Title = 'Graphical Results';
            app.GridLayout4 = uigridlayout(app.GraphicalResultsTab);
            app.ExportDropDownLabel = uilabel(app.GridLayout4);
            app.ExportDropDownLabel.Text = 'Export';
            app.ExportDropDown = uidropdown(app.GridLayout4);
            app.ExportDropDown.Items = {'-Select-', 'Data (Excel)', 'Data (txt)'};
            app.ExportDropDown.ValueChangedFcn = createCallbackFcn(app, @ExportDropDownValueChanged, true);
            app.ExportDropDown.Value = '-Select-';
            app.TabGroup2 = uitabgroup(app.GridLayout4);
            app.GeneralResultsTab = uitab(app.TabGroup2);
            app.GeneralResultsTab.Title = 'General Results';
            app.GridLayout23 = uigridlayout(app.GeneralResultsTab);
            app.GraphGeneralVoltage = uiaxes(app.GridLayout23);
            title(app.GraphGeneralVoltage, 'Voltage')
            xlabel(app.GraphGeneralVoltage, 'T(s)')
            ylabel(app.GraphGeneralVoltage, 'V')
            app.GraphGeneralCurrent = uiaxes(app.GridLayout23);
            title(app.GraphGeneralCurrent, 'Current')
            xlabel(app.GraphGeneralCurrent, 'T(s)')
            ylabel(app.GraphGeneralCurrent, 'A')
            app.GraphGeneralSpeed = uiaxes(app.GridLayout23);
            title(app.GraphGeneralSpeed, 'Speed')
            xlabel(app.GraphGeneralSpeed, 'T(s)')
            ylabel(app.GraphGeneralSpeed, 'rpm')
            app.GraphGeneralTorque = uiaxes(app.GridLayout23);
            title(app.GraphGeneralTorque, 'Torque')
            xlabel(app.GraphGeneralTorque, 'T(s)')
            ylabel(app.GraphGeneralTorque, 'N.m')
            app.ElectromagneticTorqueTab = uitab(app.TabGroup2);
            app.ElectromagneticTorqueTab.Title = 'Electro-magnetic Torque';
            app.GridLayout20 = uigridlayout(app.ElectromagneticTorqueTab);
            app.GraphTabTorque = uiaxes(app.GridLayout20);
            title(app.GraphTabTorque, '')
            xlabel(app.GraphTabTorque, 'T(s)')
            ylabel(app.GraphTabTorque, 'N.m')
            app.SpeedTab = uitab(app.TabGroup2);
            app.SpeedTab.Title = 'Speed';
            app.GridLayout25 = uigridlayout(app.SpeedTab);
            app.GraphTabSpeed = uiaxes(app.GridLayout25);
            title(app.GraphTabSpeed, '')
            xlabel(app.GraphTabSpeed, 'T(s)')
            ylabel(app.GraphTabSpeed, 'rpm')
            app.PhaseCurrentsStatorTab = uitab(app.TabGroup2);
            app.PhaseCurrentsStatorTab.Title = 'Phase Currents (Stator)';
            app.GridLayout22 = uigridlayout(app.PhaseCurrentsStatorTab);
            app.GraphTabCurrent = uiaxes(app.GridLayout22);
            title(app.GraphTabCurrent, '')
            xlabel(app.GraphTabCurrent, 'T(s)')
            ylabel(app.GraphTabCurrent, 'A')
            app.VoltageTab = uitab(app.TabGroup2);
            app.VoltageTab.Title = 'Voltage';
            app.GridLayout24 = uigridlayout(app.VoltageTab);
            app.GraphTabVoltage = uiaxes(app.GridLayout24);
            title(app.GraphTabVoltage, '')
            xlabel(app.GraphTabVoltage, 'X')
            ylabel(app.GraphTabVoltage, 'Y')
            app.ReadMeTab = uitab(app.TabGroup);
            app.ReadMeTab.Title = 'Read Me';
            app.GridLayout21 = uigridlayout(app.ReadMeTab);
            app.ModeTextAreaLabel = uilabel(app.GridLayout21);
            app.ModeTextAreaLabel.Text = 'Mode';
            app.ModeTextArea = uitextarea(app.GridLayout21);
            app.ModeTextArea.Editable = 'off';
            app.ModeTextArea.Value = {'The mode pannel is composed of two lights: green and red. The green corresponds to the working mode or "on" mode. The purpose of this app is to emulate an electric drive motor. This visual only shows the student if the speed is correct, such that they do not go into generation mode.'};
            app.MotorTextAreaLabel = uilabel(app.GridLayout21);
            app.MotorTextAreaLabel.Text = 'Motor';
            app.MotorTextArea = uitextarea(app.GridLayout21);
            app.MotorTextArea.Editable = 'off';
            app.MotorTextArea.Value = {'In order to get visual results, we first need to inputs the parameters of the motor. Once they have been set and saved, the student will be able to go into the "Graphical Results". If the button "set parameters" has not been set, the latest will not open. (To be implemented once I found out how to send the information to the model).'};
            app.MotorTextArea_2Label = uilabel(app.GridLayout21);
            app.MotorTextArea_2Label.Text = 'Motor';
            app.MotorTextArea_2 = uitextarea(app.GridLayout21);
            app.MotorTextArea_2.Editable = 'off';
            app.MotorTextArea_2.Value = {'In order to get visual results, we first need to inputs the parameters of the motor. Once they have been set and saved, the student will be able to go into the "Graphical Results". If the button "set parameters" has not been set, the latest will not open. (To be implemented once I found out how to send the information to the model).'};
            app.RelevantInformationTextAreaLabel = uilabel(app.GridLayout21);
            app.RelevantInformationTextAreaLabel.Text = 'Relevant Information';
            app.RelevantInformationTextArea = uitextarea(app.GridLayout21);
            app.RelevantInformationTextArea.Editable = 'off';
            app.RelevantInformationTextArea.Value = {'Concordia Security'; ''; '514-848-3717'; ''; 'Dial 1 for urgent situations'; 'Dial 2 for non-urgent situations'; ''; 'In case of emergency, contact 9-1-1'; ''; 'For Licensing questions: inquiry@eruditio.com'};
            % Show the figure after all components are created
            app.GridLaboratoryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GridLab(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GridLaboratoryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GridLaboratoryUIFigure)
        end
    end
end