classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConcordiaUniversityEruditioUIFigure  matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        Image              matlab.ui.control.Image
        TextArea           matlab.ui.control.TextArea
        Experiment1Button  matlab.ui.control.Button
        Experiment2Button  matlab.ui.control.Button
        TextArea_6         matlab.ui.control.TextArea
        TextArea_5         matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        Experiment1Object % Description
        Experiment2Object % Description
        %Lab3Object % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.ConcordiaUniversityEruditioUIFigure,'center');% Please don't remove this, it's the thrid time I add it. AT
        end

        % Button pushed function: Experiment1Button
        function Experiment1ButtonPushed(app, event)
            app.Experiment1Button.Enable = 'off';
            app.Experiment1Object = GridLab(app);
        end

        % Button pushed function: Experiment2Button
        function Experiment2ButtonPushed(app, event)
            app.Experiment2Button.Enable = 'off';
            app.Experiment2Object = VFLab(app);
        end

        % Close request function: 
        % ConcordiaUniversityEruditioUIFigure
        function ConcordiaUniversityEruditioUIFigureCloseRequest(app, event)
            
            delete(app.Experiment1Object)
            delete(app.Experiment2Object)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConcordiaUniversityEruditioUIFigure = uifigure('Visible', 'off');
            app.ConcordiaUniversityEruditioUIFigure.Name = 'Concordia University - Eruditio';
            app.ConcordiaUniversityEruditioUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConcordiaUniversityEruditioUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ConcordiaUniversityEruditioUIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'Capture.PNG';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Value = {'Welcome to Eruditio! '; ''; 'Eruditio stands for knowledge and learning. Our goal is to provide software-based services that recreates an induction motor. You will be able to use different control schemes, providing you accurate data and visuals to help you reach your learning goals.'};
            app.Experiment1Button = uibutton(app.GridLayout, 'push');
            app.Experiment1Button.ButtonPushedFcn = createCallbackFcn(app, @Experiment1ButtonPushed, true);
            app.Experiment1Button.Text = 'Experiment 1';
            app.Experiment2Button = uibutton(app.GridLayout, 'push');
            app.Experiment2Button.ButtonPushedFcn = createCallbackFcn(app, @Experiment2ButtonPushed, true);
            app.Experiment2Button.Text = 'Experiment 2';
            app.TextArea_6 = uitextarea(app.GridLayout);
            app.TextArea_6.Value = {'Grid Laboratory'};
            app.TextArea_5 = uitextarea(app.GridLayout);
            app.TextArea_5.Value = {'VF Laboratory'};
            % Show the figure after all components are created
            app.ConcordiaUniversityEruditioUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConcordiaUniversityEruditioUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConcordiaUniversityEruditioUIFigure)
        end
    end
end