classdef VFLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VFLaboratoryUIFigure         matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ControlpannelTab             matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        LoadPanel_3                  matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        MotorLoadPanel               matlab.ui.container.Panel
        LoadKnobLabel                matlab.ui.control.Label
        LoadKnob                     matlab.ui.control.Knob
        ControlPanel_4               matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        VfPanel                      matlab.ui.container.Panel
        PWMInverterPanel_5           matlab.ui.container.Panel
        VaLamp_11Label               matlab.ui.control.Label
        VaLamp_11                    matlab.ui.control.Lamp
        VbLamp_11Label               matlab.ui.control.Label
        VbLamp_11                    matlab.ui.control.Lamp
        VcLamp_11Label               matlab.ui.control.Label
        VcLamp_11                    matlab.ui.control.Lamp
        VoltageVKnob_11Label         matlab.ui.control.Label
        VoltageVKnob_11              matlab.ui.control.Knob
        maEditField_5Label           matlab.ui.control.Label
        maEditField_5                matlab.ui.control.NumericEditField
        mfEditField_5Label           matlab.ui.control.Label
        mfEditField_5                matlab.ui.control.NumericEditField
        VEditField_11Label           matlab.ui.control.Label
        VEditField_11                matlab.ui.control.NumericEditField
        SequenceControlPanel         matlab.ui.container.Panel
        StartButton                  matlab.ui.control.StateButton
        StopButton                   matlab.ui.control.StateButton
        MotorParametersTab           matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        Panel2                       matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        PolesSpinner_2Label          matlab.ui.control.Label
        PolesSpinner_2               matlab.ui.control.Spinner
        EditField                    matlab.ui.control.EditField
        FrequencySlider_2Label       matlab.ui.control.Label
        FrequencySlider_2            matlab.ui.control.Slider
        Panel                        matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        RsEditFieldLabel             matlab.ui.control.Label
        RsEditField                  matlab.ui.control.NumericEditField
        XsEditFieldLabel             matlab.ui.control.Label
        XsEditField                  matlab.ui.control.NumericEditField
        XmEditFieldLabel             matlab.ui.control.Label
        XmEditField                  matlab.ui.control.NumericEditField
        XrEditFieldLabel             matlab.ui.control.Label
        XrEditField                  matlab.ui.control.NumericEditField
        Image_2                      matlab.ui.control.Image
        PHASEINDUCTIONMOTORLabel_2   matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        MotorLoadPanel_2             matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        LoadKnob_2Label              matlab.ui.control.Label
        LoadKnob_2                   matlab.ui.control.Knob
        ValueEditFieldLabel          matlab.ui.control.Label
        ValueEditField               matlab.ui.control.EditField
        SetParametersButton_2        matlab.ui.control.Button
        PWMInverterPanel_7           matlab.ui.container.Panel
        GridLayout10_2               matlab.ui.container.GridLayout
        Panel3                       matlab.ui.container.Panel
        GridLayout11                 matlab.ui.container.GridLayout
        maEditField_7Label           matlab.ui.control.Label
        maEditField_7                matlab.ui.control.NumericEditField
        mfEditField_7Label           matlab.ui.control.Label
        mfEditField_7                matlab.ui.control.NumericEditField
        VEditField_13Label           matlab.ui.control.Label
        VEditField_13                matlab.ui.control.NumericEditField
        VbLamp_13Label               matlab.ui.control.Label
        VbLamp_13                    matlab.ui.control.Lamp
        VcLamp_13Label               matlab.ui.control.Label
        VcLamp_13                    matlab.ui.control.Lamp
        VaLamp_13Label               matlab.ui.control.Label
        VaLamp_13                    matlab.ui.control.Lamp
        VoltageVKnob_13              matlab.ui.control.Knob
        VoltageEditFieldLabel        matlab.ui.control.Label
        VoltageEditField             matlab.ui.control.EditField
        GraphicalResultsTab          matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        ExportDropDownLabel          matlab.ui.control.Label
        ExportDropDown               matlab.ui.control.DropDown
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_5                     matlab.ui.control.UIAxes
        ReadMeTab                    matlab.ui.container.Tab
        GridLayout9                  matlab.ui.container.GridLayout
        ModeTextAreaLabel            matlab.ui.control.Label
        ModeTextArea                 matlab.ui.control.TextArea
        MotorTextAreaLabel           matlab.ui.control.Label
        MotorTextArea                matlab.ui.control.TextArea
        PWMTextAreaLabel             matlab.ui.control.Label
        PWMTextArea                  matlab.ui.control.TextArea
        HelpTextAreaLabel            matlab.ui.control.Label
        HelpTextArea                 matlab.ui.control.TextArea
        MotorTextArea_2Label         matlab.ui.control.Label
        MotorTextArea_2              matlab.ui.control.TextArea
        RelevantInformationTextAreaLabel  matlab.ui.control.Label
        RelevantInformationTextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        callingApp % Description
    end
    
    methods (Access = private)
        
        function getModelValues(app)
            %vDCFromModel =  get_param([bdroot,'/Vdc'],'Value');
            
            %app.VEditField_11.Value =  str2double(vDCFromModel);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
           
            app.callingApp = mainApp;
            movegui(app.VFLaboratoryUIFigure,'center');
            %load_system('simulation\PWMJan27th.slx');   %loads the system into temp memory
            % so that the command 'bdroot' returns the last Simulink model loaded
            
            %getModelValues(app);
        end

        % Callback function
        function VoltageVKnob_2ValueChanging(app, event)
            changingValue = event.Value;
            voltKnob = num2str(changingValue); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/Vdc'],'Value', voltKnob);
            getModelValues(app);
        end

        % Close request function: VFLaboratoryUIFigure
        function VFLaboratoryUIFigureCloseRequest(app, event)
            app.callingApp.Laboratory2Button.Enable = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VFLaboratoryUIFigure = uifigure('Visible', 'off');
            app.VFLaboratoryUIFigure.Name = 'VF Laboratory';
            app.VFLaboratoryUIFigure.CloseRequestFcn = createCallbackFcn(app, @VFLaboratoryUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.VFLaboratoryUIFigure);
            app.ControlpannelTab = uitab(app.TabGroup);
            app.ControlpannelTab.Title = 'Control pannel';
            app.GridLayout = uigridlayout(app.ControlpannelTab);
            app.LoadPanel_3 = uipanel(app.GridLayout);
            app.LoadPanel_3.Tooltip = {''};
            app.LoadPanel_3.Title = 'Load';
            app.LoadPanel_3.Tag = 'three states';
            app.GridLayout3 = uigridlayout(app.LoadPanel_3);
            app.MotorLoadPanel = uipanel(app.GridLayout3);
            app.MotorLoadPanel.Title = 'Motor Load';
            app.LoadKnobLabel = uilabel(app.MotorLoadPanel);
            app.LoadKnobLabel.Text = 'Load';
            app.LoadKnob = uiknob(app.MotorLoadPanel, 'continuous');
            app.LoadKnob.Limits = [0 10000000];
            app.ControlPanel_4 = uipanel(app.GridLayout);
            app.ControlPanel_4.Tooltip = {''};
            app.ControlPanel_4.Title = 'Control';
            app.ControlPanel_4.Tag = 'three states';
            app.GridLayout2 = uigridlayout(app.ControlPanel_4);
            app.VfPanel = uipanel(app.GridLayout2);
            app.VfPanel.Title = 'V/f';
            app.PWMInverterPanel_5 = uipanel(app.GridLayout);
            app.PWMInverterPanel_5.Title = 'PWM Inverter';
            app.VaLamp_11Label = uilabel(app.PWMInverterPanel_5);
            app.VaLamp_11Label.Text = 'Va';
            app.VaLamp_11 = uilamp(app.PWMInverterPanel_5);
            app.VbLamp_11Label = uilabel(app.PWMInverterPanel_5);
            app.VbLamp_11Label.Text = 'Vb';
            app.VbLamp_11 = uilamp(app.PWMInverterPanel_5);
            app.VcLamp_11Label = uilabel(app.PWMInverterPanel_5);
            app.VcLamp_11Label.Text = 'Vc';
            app.VcLamp_11 = uilamp(app.PWMInverterPanel_5);
            app.VoltageVKnob_11Label = uilabel(app.PWMInverterPanel_5);
            app.VoltageVKnob_11Label.Text = 'Voltage [V]';
            app.VoltageVKnob_11 = uiknob(app.PWMInverterPanel_5, 'continuous');
            app.VoltageVKnob_11.Limits = [0 250];
            app.maEditField_5Label = uilabel(app.PWMInverterPanel_5);
            app.maEditField_5Label.Text = 'ma';
            app.maEditField_5 = uieditfield(app.PWMInverterPanel_5, 'numeric');
            app.mfEditField_5Label = uilabel(app.PWMInverterPanel_5);
            app.mfEditField_5Label.Text = 'mf';
            app.mfEditField_5 = uieditfield(app.PWMInverterPanel_5, 'numeric');
            app.VEditField_11Label = uilabel(app.PWMInverterPanel_5);
            app.VEditField_11Label.Text = '[V]';
            app.VEditField_11 = uieditfield(app.PWMInverterPanel_5, 'numeric');
            app.VEditField_11.Editable = 'off';
            app.VEditField_11.Tooltip = {'Voltage set by the knob'};
            app.SequenceControlPanel = uipanel(app.GridLayout);
            app.SequenceControlPanel.Title = 'Sequence Control';
            app.StartButton = uibutton(app.SequenceControlPanel, 'state');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.SequenceControlPanel, 'state');
            app.StopButton.Text = 'Stop';
            app.MotorParametersTab = uitab(app.TabGroup);
            app.MotorParametersTab.Title = 'Motor Parameters';
            app.GridLayout5 = uigridlayout(app.MotorParametersTab);
            app.Panel2 = uipanel(app.GridLayout5);
            app.Panel2.Title = 'Panel2';
            app.GridLayout7 = uigridlayout(app.Panel2);
            app.PolesSpinner_2Label = uilabel(app.GridLayout7);
            app.PolesSpinner_2Label.Text = 'Poles';
            app.PolesSpinner_2 = uispinner(app.GridLayout7);
            app.PolesSpinner_2.Limits = [0 Inf];
            app.EditField = uieditfield(app.GridLayout7, 'text');
            app.FrequencySlider_2Label = uilabel(app.GridLayout7);
            app.FrequencySlider_2Label.Text = 'Frequency ';
            app.FrequencySlider_2 = uislider(app.GridLayout7);
            app.Panel = uipanel(app.GridLayout5);
            app.Panel.Title = 'Panel';
            app.GridLayout6 = uigridlayout(app.Panel);
            app.RsEditFieldLabel = uilabel(app.GridLayout6);
            app.RsEditFieldLabel.Text = 'Rs';
            app.RsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.RsEditField.Value = 1;
            app.XsEditFieldLabel = uilabel(app.GridLayout6);
            app.XsEditFieldLabel.Text = 'Xs';
            app.XsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XmEditFieldLabel = uilabel(app.GridLayout6);
            app.XmEditFieldLabel.Text = 'Xm';
            app.XmEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XrEditFieldLabel = uilabel(app.GridLayout6);
            app.XrEditFieldLabel.Text = 'Xr';
            app.XrEditField = uieditfield(app.GridLayout6, 'numeric');
            app.Image_2 = uiimage(app.GridLayout5);
            app.Image_2.ImageSource = 'EquivalentCircuitDiagram.png';
            app.PHASEINDUCTIONMOTORLabel_2 = uilabel(app.GridLayout5);
            app.PHASEINDUCTIONMOTORLabel_2.Text = '3-PHASE INDUCTION MOTOR';
            app.TextArea = uitextarea(app.GridLayout5);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'* The values shown are pre-set values. They can be change at any given moment during the lab experiment'};
            app.MotorLoadPanel_2 = uipanel(app.GridLayout5);
            app.MotorLoadPanel_2.Title = 'Motor Load';
            app.GridLayout10 = uigridlayout(app.MotorLoadPanel_2);
            app.LoadKnob_2Label = uilabel(app.GridLayout10);
            app.LoadKnob_2Label.Text = 'Load';
            app.LoadKnob_2 = uiknob(app.GridLayout10, 'continuous');
            app.LoadKnob_2.Limits = [0 10000000];
            app.ValueEditFieldLabel = uilabel(app.GridLayout10);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.GridLayout10, 'text');
            app.SetParametersButton_2 = uibutton(app.GridLayout5, 'push');
            app.SetParametersButton_2.Text = 'Set Parameters';
            app.PWMInverterPanel_7 = uipanel(app.GridLayout5);
            app.PWMInverterPanel_7.Title = 'PWM Inverter';
            app.GridLayout10_2 = uigridlayout(app.PWMInverterPanel_7);
            app.Panel3 = uipanel(app.GridLayout10_2);
            app.Panel3.Title = 'Panel3';
            app.GridLayout11 = uigridlayout(app.Panel3);
            app.maEditField_7Label = uilabel(app.GridLayout11);
            app.maEditField_7Label.Text = 'ma';
            app.maEditField_7 = uieditfield(app.GridLayout11, 'numeric');
            app.mfEditField_7Label = uilabel(app.GridLayout11);
            app.mfEditField_7Label.Text = 'mf';
            app.mfEditField_7 = uieditfield(app.GridLayout11, 'numeric');
            app.VEditField_13Label = uilabel(app.GridLayout11);
            app.VEditField_13Label.Text = '[V]';
            app.VEditField_13 = uieditfield(app.GridLayout11, 'numeric');
            app.VEditField_13.Editable = 'off';
            app.VEditField_13.Tooltip = {'Voltage set by the knob'};
            app.VbLamp_13Label = uilabel(app.GridLayout10_2);
            app.VbLamp_13Label.Text = 'Vb';
            app.VbLamp_13 = uilamp(app.GridLayout10_2);
            app.VcLamp_13Label = uilabel(app.GridLayout10_2);
            app.VcLamp_13Label.Text = 'Vc';
            app.VcLamp_13 = uilamp(app.GridLayout10_2);
            app.VaLamp_13Label = uilabel(app.GridLayout10_2);
            app.VaLamp_13Label.Text = 'Va';
            app.VaLamp_13 = uilamp(app.GridLayout10_2);
            app.VoltageVKnob_13 = uiknob(app.GridLayout10_2, 'continuous');
            app.VoltageVKnob_13.Limits = [0 250];
            app.VoltageEditFieldLabel = uilabel(app.GridLayout10_2);
            app.VoltageEditFieldLabel.Text = 'Voltage ';
            app.VoltageEditField = uieditfield(app.GridLayout10_2, 'text');
            app.GraphicalResultsTab = uitab(app.TabGroup);
            app.GraphicalResultsTab.Title = 'Graphical Results';
            app.GridLayout4 = uigridlayout(app.GraphicalResultsTab);
            app.ExportDropDownLabel = uilabel(app.GridLayout4);
            app.ExportDropDownLabel.Text = 'Export';
            app.ExportDropDown = uidropdown(app.GridLayout4);
            app.ExportDropDown.Items = {'Data (Excel)', 'Data (txt)', 'Images (pdf)', 'Images (word)'};
            app.ExportDropDown.Value = 'Data (Excel)';
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.GridLayout4);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.GridLayout4);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.GridLayout4);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes_5 = uiaxes(app.GridLayout4);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            app.ReadMeTab = uitab(app.TabGroup);
            app.ReadMeTab.Title = 'Read Me';
            app.GridLayout9 = uigridlayout(app.ReadMeTab);
            app.ModeTextAreaLabel = uilabel(app.GridLayout9);
            app.ModeTextAreaLabel.Text = 'Mode';
            app.ModeTextArea = uitextarea(app.GridLayout9);
            app.ModeTextArea.Editable = 'off';
            app.ModeTextArea.Value = {'The mode pannel is composed of two lights: green and red. The green corresponds to the working mode or "on" mode. The purpose of this app is to emulate an electric drive motor. This visual only shows the student if the speed is correct, such that they do not go into generation mode.'};
            app.MotorTextAreaLabel = uilabel(app.GridLayout9);
            app.MotorTextAreaLabel.Text = 'Motor';
            app.MotorTextArea = uitextarea(app.GridLayout9);
            app.MotorTextArea.Editable = 'off';
            app.MotorTextArea.Value = {'In order to get visual results, we first need to inputs the parameters of the motor. Once they have been set and saved, the student will be able to go into the "Graphical Results". If the button "set parameters" has not been set, the latest will not open. (To be implemented once I found out how to send the information to the model).'};
            app.PWMTextAreaLabel = uilabel(app.GridLayout9);
            app.PWMTextAreaLabel.Text = 'PWM';
            app.PWMTextArea = uitextarea(app.GridLayout9);
            app.PWMTextArea.Editable = 'off';
            app.HelpTextAreaLabel = uilabel(app.GridLayout9);
            app.HelpTextAreaLabel.Text = 'Help';
            app.HelpTextArea = uitextarea(app.GridLayout9);
            app.HelpTextArea.Editable = 'off';
            app.HelpTextArea.Value = {'This option will allow you the student to contact administrators, or security in case of urgence.'};
            app.MotorTextArea_2Label = uilabel(app.GridLayout9);
            app.MotorTextArea_2Label.Text = 'Motor';
            app.MotorTextArea_2 = uitextarea(app.GridLayout9);
            app.MotorTextArea_2.Editable = 'off';
            app.MotorTextArea_2.Value = {'In order to get visual results, we first need to inputs the parameters of the motor. Once they have been set and saved, the student will be able to go into the "Graphical Results". If the button "set parameters" has not been set, the latest will not open. (To be implemented once I found out how to send the information to the model).'};
            app.RelevantInformationTextAreaLabel = uilabel(app.GridLayout9);
            app.RelevantInformationTextAreaLabel.Text = 'Relevant Information';
            app.RelevantInformationTextArea = uitextarea(app.GridLayout9);
            app.RelevantInformationTextArea.Editable = 'off';
            app.RelevantInformationTextArea.Value = {'Concordia Security'; ''; '514-848-3717'; ''; 'Dial 1 for urgent situations'; 'Dial 2 for non-urgent situations'; ''; 'In case of emergency, contact 9-1-1'; ''; 'For Licensing questions: inquiry@eruditio.com'};
            % Show the figure after all components are created
            app.VFLaboratoryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VFLab(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VFLaboratoryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VFLaboratoryUIFigure)
        end
    end
end